classdef Assignment21 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        LSS_imperial         matlab.ui.control.NumericEditField
        LSSktsLabel          matlab.ui.control.Label
        Density_imp          matlab.ui.control.NumericEditField
        Densitylbsft2Label   matlab.ui.control.Label
        Pressure_imp         matlab.ui.control.NumericEditField
        PressureinHgLabel    matlab.ui.control.Label
        Temp_imp             matlab.ui.control.NumericEditField
        TemperatureFLabel    matlab.ui.control.Label
        Alt_imp              matlab.ui.control.NumericEditField
        AltitudeftLabel      matlab.ui.control.Label
        ImperialsystemLabel  matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        LSS_metric           matlab.ui.control.NumericEditField
        LSSmsLabel           matlab.ui.control.Label
        Density_metric       matlab.ui.control.NumericEditField
        Densitykgm3Label     matlab.ui.control.Label
        Pressure_metric      matlab.ui.control.NumericEditField
        PressurePaLabel      matlab.ui.control.Label
        Temp_metric          matlab.ui.control.NumericEditField
        TemperatureCLabel    matlab.ui.control.Label
        Alt_metric           matlab.ui.control.NumericEditField
        AltitudemLabel       matlab.ui.control.Label
        MetricsystemLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        K2C = -273.15 % Description
        m2ft = 3.28084 % Description
        Pa2PSI = 0.00014 % Description
        density_m2density_imp = 0.06243;
        a2aimp = 1.943844;
        R = 8.3144598; %universal gas constant
        M=0.0289644; %molar mass of Earth's air
        L=0.0065; %temperature lapse rate in the troposphere
        g0=9.80665; %gravitational acceleration
    end
    
    methods (Access = private)
        
        function ISA_gen(app)
            [app.Temp_metric.Value, app.LSS_metric.Value, app.Pressure_metric.Value, app.Density_metric.Value] = atmosisa(app.Alt_metric.Value);            
        end
        
        function convertImp(app)
            app.Alt_imp.Value = app.Alt_metric.Value .* app.m2ft;
            app.Temp_imp.Value = ((app.Temp_metric.Value - 273.15) * 1.8) + 32;
            app.Pressure_imp.Value = app.Pressure_metric.Value * app.Pa2PSI;
            app.Density_imp.Value = app.Density_metric.Value * app.density_m2density_imp;
            app.LSS_imperial.Value = app.LSS_metric.Value * app.a2aimp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Alt_metric
        function Alt_metricValueChanged(app, event)
            ISA_gen(app);
            convertImp(app);
        end

        % Value changed function: Alt_imp
        function Alt_impValueChanged(app, event)
            app.Alt_metric.Value = app.Alt_imp.Value / app.m2ft;
            ISA_gen(app);
            convertImp(app);
        end

        % Value changed function: Temp_imp
        function Temp_impValueChanged(app, event)
            app.Temp_metric.Value = (app.Temp_imp.Value - 32) / 1.8 + 273.15;
            app.Alt_imp.Value = -(app.Temp_metric.Value-273.15 - 15) * 1000/1.98;
            app.Alt_metric.Value = app.Alt_imp.Value / app.m2ft;
            ISA_gen(app)
            convertImp(app);
        end

        % Value changed function: Temp_metric
        function Temp_metricValueChanged(app, event)
            app.Alt_imp.Value = -(app.Temp_metric.Value-273.15 - 15) * 1000/1.98;
            app.Alt_metric.Value = app.Alt_imp.Value / app.m2ft;
            ISA_gen(app)
            convertImp(app);
        end

        % Value changed function: Pressure_metric
        function Pressure_metricValueChanged(app, event)
            app.Alt_metric.Value = 288.15/app.L * (1 - (app.Pressure_metric.Value/101325)^(app.R*app.L/app.g0*app.M));
            ISA_gen(app);
            convertImp(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ImperialsystemLabel = uilabel(app.LeftPanel);
            app.ImperialsystemLabel.Text = 'Imperial system';
            app.AltitudeftLabel = uilabel(app.LeftPanel);
            app.AltitudeftLabel.Text = 'Altitude (ft)';
            app.Alt_imp = uieditfield(app.LeftPanel, 'numeric');
            app.Alt_imp.ValueChangedFcn = createCallbackFcn(app, @Alt_impValueChanged, true);
            app.TemperatureFLabel = uilabel(app.LeftPanel);
            app.TemperatureFLabel.Text = 'Temperature (F)';
            app.Temp_imp = uieditfield(app.LeftPanel, 'numeric');
            app.Temp_imp.ValueChangedFcn = createCallbackFcn(app, @Temp_impValueChanged, true);
            app.PressureinHgLabel = uilabel(app.LeftPanel);
            app.PressureinHgLabel.Text = 'Pressure(in/Hg)';
            app.Pressure_imp = uieditfield(app.LeftPanel, 'numeric');
            app.Densitylbsft2Label = uilabel(app.LeftPanel);
            app.Densitylbsft2Label.Text = 'Density (lbs/ft^3)';
            app.Density_imp = uieditfield(app.LeftPanel, 'numeric');
            app.LSSktsLabel = uilabel(app.LeftPanel);
            app.LSSktsLabel.Text = 'LSS (kts)';
            app.LSS_imperial = uieditfield(app.LeftPanel, 'numeric');
            app.LSS_imperial.Editable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.MetricsystemLabel = uilabel(app.RightPanel);
            app.MetricsystemLabel.Text = 'Metric system';
            app.AltitudemLabel = uilabel(app.RightPanel);
            app.AltitudemLabel.Text = 'Altitude (m)';
            app.Alt_metric = uieditfield(app.RightPanel, 'numeric');
            app.Alt_metric.ValueChangedFcn = createCallbackFcn(app, @Alt_metricValueChanged, true);
            app.TemperatureCLabel = uilabel(app.RightPanel);
            app.TemperatureCLabel.Text = 'Temperature (K)';
            app.Temp_metric = uieditfield(app.RightPanel, 'numeric');
            app.Temp_metric.ValueChangedFcn = createCallbackFcn(app, @Temp_metricValueChanged, true);
            app.PressurePaLabel = uilabel(app.RightPanel);
            app.PressurePaLabel.Text = 'Pressure (Pa)';
            app.Pressure_metric = uieditfield(app.RightPanel, 'numeric');
            app.Pressure_metric.ValueChangedFcn = createCallbackFcn(app, @Pressure_metricValueChanged, true);
            app.Densitykgm3Label = uilabel(app.RightPanel);
            app.Densitykgm3Label.Text = 'Density ( kg/m^3)';
            app.Density_metric = uieditfield(app.RightPanel, 'numeric');
            app.LSSmsLabel = uilabel(app.RightPanel);
            app.LSSmsLabel.Text = 'LSS (m/s)';
            app.LSS_metric = uieditfield(app.RightPanel, 'numeric');
            app.LSS_metric.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment21

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end