classdef Assignment22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        X0EditField                matlab.ui.control.NumericEditField
        X0EditFieldLabel           matlab.ui.control.Label
        X1EditField                matlab.ui.control.NumericEditField
        X1EditFieldLabel           matlab.ui.control.Label
        X2EditField                matlab.ui.control.NumericEditField
        X2EditFieldLabel           matlab.ui.control.Label
        X3EditField                matlab.ui.control.NumericEditField
        X3EditFieldLabel           matlab.ui.control.Label
        X4EditField                matlab.ui.control.NumericEditField
        X4EditFieldLabel           matlab.ui.control.Label
        X5EditField                matlab.ui.control.NumericEditField
        X5EditFieldLabel           matlab.ui.control.Label
        EnterthecoefficientsLabel  matlab.ui.control.Label
        PolynomsolverLabel         matlab.ui.control.Label
        SOLVEButton                matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: X5EditField
        function X5EditFieldValueChanged(app, event)
            c5 = app.X5EditField.Value;
            
        end

        % Value changed function: X4EditField
        function X4EditFieldValueChanged(app, event)
            c4 = app.X4EditField.Value;
            
        end

        % Value changed function: X3EditField
        function X3EditFieldValueChanged(app, event)
            c3 = app.X3EditField.Value;
            
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            c2 = app.X2EditField.Value;
            
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            c1 = app.X1EditField.Value;
            
        end

        % Value changed function: X0EditField
        function X0EditFieldValueChanged(app, event)
            c0 = app.X0EditField.Value;
            
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            x = linspace(-3, 3);
            p =  [app.X5EditField.Value, app.X4EditField.Value, app.X3EditField.Value, app.X2EditField.Value, app.X1EditField.Value, app.X0EditField.Value];
            polyn = app.X5EditField.Value*x.^5 + app.X4EditField.Value*x.^4 + app.X3EditField.Value*x.^3 + app.X2EditField.Value*x.^2 + app.X1EditField.Value*x + app.X0EditField.Value;
            plot(app.UIAxes, x, polyn)
            r = roots(p);
            rl = r(r<=3 & r>=-3);
            app.x0EditField.Value = rl(1);
            app.x2EditField.Value = rl(2);
            app.x3EditField.Value = rl(3);
            app.x4EditField.Value = rl(4);
            app.x5EditField.Value = rl(5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Polynom representation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'P(X)')
            zlabel(app.UIAxes, 'Z')
            app.SOLVEButton = uibutton(app.UIFigure, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            app.PolynomsolverLabel = uilabel(app.UIFigure);
            app.PolynomsolverLabel.Text = 'Polynom solver';
            app.EnterthecoefficientsLabel = uilabel(app.UIFigure);
            app.EnterthecoefficientsLabel.Text = 'Enter the coefficients';
            app.X5EditFieldLabel = uilabel(app.UIFigure);
            app.X5EditFieldLabel.Text = 'X^5';
            app.X5EditField = uieditfield(app.UIFigure, 'numeric');
            app.X5EditField.ValueChangedFcn = createCallbackFcn(app, @X5EditFieldValueChanged, true);
            app.X4EditFieldLabel = uilabel(app.UIFigure);
            app.X4EditFieldLabel.Text = 'X^4';
            app.X4EditField = uieditfield(app.UIFigure, 'numeric');
            app.X4EditField.ValueChangedFcn = createCallbackFcn(app, @X4EditFieldValueChanged, true);
            app.X3EditFieldLabel = uilabel(app.UIFigure);
            app.X3EditFieldLabel.Text = 'X^3';
            app.X3EditField = uieditfield(app.UIFigure, 'numeric');
            app.X3EditField.ValueChangedFcn = createCallbackFcn(app, @X3EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X^2';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X^1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X0EditFieldLabel = uilabel(app.UIFigure);
            app.X0EditFieldLabel.Text = 'X^0';
            app.X0EditField = uieditfield(app.UIFigure, 'numeric');
            app.X0EditField.ValueChangedFcn = createCallbackFcn(app, @X0EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Assignment22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end