classdef Course13 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GenerateButton        matlab.ui.control.Button
        NPhrasesSpinner       matlab.ui.control.Spinner
        NPhrasesSpinnerLabel  matlab.ui.control.Label
        NWordsSpinner         matlab.ui.control.Spinner
        NWordsSpinnerLabel    matlab.ui.control.Label
        Slider                matlab.ui.control.RangeSlider
        SliderLabel           matlab.ui.control.Label
        Rule3                 matlab.ui.control.CheckBox
        Rule2                 matlab.ui.control.CheckBox
        Rule1                 matlab.ui.control.CheckBox
        StoryTextArea         matlab.ui.control.TextArea
        StoryTextAreaLabel    matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = gen_word(app)
            aux = app.Slider.Value;
            Nmin = aux(1);
            Nmax = aux(2);
            wo = 'aeiouy';
            word = [];
            ck = 0;
            while ck == 0
                ck = 1;
                N = randi(Nmax-Nmin-1) + Nmin;
                for i=1:N
                    aux = randi(26) + 'a'-1;
                    word = [word aux];
                end
                if app.Rule1.Value > 0 || app.Rule2.Value > 0 || app.Rule3.Value > 0
                    jw = 0;
                    jc = 0;
                    jd = 0;
                    for i=1:N
                        if any(find(wo == word(i)))
                            jw = jw+1;
                            jc = 0;
                            if jw > 2
                                break
                            end
                        else
                            jw = 0;
                            jc = jc+1;
                            if jc > 2
                                break
                            end
                        end
                        if i<N
                            if word(i) == word(i+1)
                                jd = jd+1;
                            end
                        end
    
                    end
                    
                    if (app.Rule1.Value > 0 && jw>2) || (app.Rule2.Value > 0 && jc>2) || ...
                        (app.Rule3.Value > 0 && jd>0)
                        ck = 0;
                    end
                end
                
            end
            results = word;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            Nw = randi(app.NWordsSpinner.Value*2);
            Np = app.NPhrasesSpinner.Value;
            for i=1:Np
                app.StoryTextArea.Value{1} = ['' gen_word(app)];
                for j=1:Nw-1

                    app.StoryTextArea.Value{i} = [app.StoryTextArea.Value{i} ' ' gen_word(app)];
                end
                app.StoryTextArea.Value{i} = [app.StoryTextArea.Value{i} '.'];
                app.StoryTextArea.Value{i}(1) = char(app.StoryTextArea.Value{i}(1)-32);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StoryTextAreaLabel = uilabel(app.UIFigure);
            app.StoryTextAreaLabel.Text = 'Story';
            app.StoryTextArea = uitextarea(app.UIFigure);
            app.StoryTextArea.Placeholder = 'The story will generate here';
            app.Rule1 = uicheckbox(app.UIFigure);
            app.Rule1.Text = '1. No more than 2 consecutive vowels';
            app.Rule2 = uicheckbox(app.UIFigure);
            app.Rule2.Text = '2. No more than 2 consecutive consonants';
            app.Rule3 = uicheckbox(app.UIFigure);
            app.Rule3.Text = '3. No double letters';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure, 'range');
            app.Slider.Limits = [2 12];
            app.Slider.Step = 1;
            app.Slider.Value = [2 12];
            app.NWordsSpinnerLabel = uilabel(app.UIFigure);
            app.NWordsSpinnerLabel.Text = 'NWords';
            app.NWordsSpinner = uispinner(app.UIFigure);
            app.NWordsSpinner.Value = 2;
            app.NPhrasesSpinnerLabel = uilabel(app.UIFigure);
            app.NPhrasesSpinnerLabel.Text = 'NPhrases';
            app.NPhrasesSpinner = uispinner(app.UIFigure);
            app.NPhrasesSpinner.Value = 2;
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Course13

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end