classdef FlyingLow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MaxUpSlider         matlab.ui.control.Slider
        MaxUpSliderLabel    matlab.ui.control.Label
        MaxDownSlider       matlab.ui.control.Slider
        MaxDownSliderLabel  matlab.ui.control.Label
        AltSlider           matlab.ui.control.Slider
        AltSliderLabel      matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x;
        z; % Description
    end
    
    methods (Access = private)
        
        function drawTrajectory(app)
            % Read current slider values
            alt     = app.AltSlider.Value;      % altitude above ground
            maxup   = app.MaxUpSlider.Value;    % maximum climb slope
            maxdown = app.MaxDownSlider.Value;  % maximum descent slope

            xx = app.x;   % 1×N vector
            zz = app.z;   % 1×N vector
            N  = numel(xx);

            % Pre‐allocate flight‐height array
            h = nan(1,N);

            % 1) At x=0, we start at terrain‐height+alt:
            h(1) = zz(1) + alt;

            % 2) March forward along the terrain
            for i = 2:N
                dx    = xx(i) - xx(i-1);      % horizontal step (here always 100)
                h_req = zz(i) + alt;          % minimum needed to clear terrain

                % Given previous height h(i-1), these are the slope limits:
                maxClimb   = h(i-1) + maxup   * dx;  % cannot climb higher than this
                maxDescent = h(i-1) - maxdown * dx;  % cannot descend below this

                % To clear terrain we need h(i) ≥ h_req.
                % Also slope constraint says:
                %    h(i) ≤ maxClimb   (can't climb faster than maxup)
                %    h(i) ≥ maxDescent (can't descend faster than maxdown)
                %
                % So the feasible h(i) must satisfy:
                %    max( h_req, maxDescent )  ≤  h(i)  ≤  maxClimb
                h_low  = max(h_req,   maxDescent);
                h_high = maxClimb;

                if h_low > h_high
                    % Infeasible: cannot both clear terrain AND respect slope.
                    % We’ll simply set h(i)=NaN to show “no‐fly” area.
                    h(i) = NaN;
                else
                    % Choose the minimal feasible height, i.e. just clearing terrain
                    h(i) = h_low;
                end
            end

            % 3) Now plot terrain and flight path together
            plot(app.UIAxes, xx, zz,    'b-', 'LineWidth', 1.2)
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, xx, h,     'r-', 'LineWidth', 1.5)
            hold(app.UIAxes, 'off')

            title(app.UIAxes, 'Terrain Profile (blue) and Flight Path (red)')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'Elevation / Flight Height')
            legend(app.UIAxes, {'Terrain','Flight Path'}, 'Location', 'best')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1) Create the terrain: x from 0 to 10000 in steps of 100
            app.x = 0:100:10000;
            % 2) Assign a random elevation to z of same length as x
            app.z = 20 + 500*rand(size(app.x));

            % 3) Give some reasonable default slider values:
            %    For example: alt = 200, max climb = 0.05, max descent = 0.07
            app.AltSlider.Value     = 100;    % “alt” above ground
            app.MaxUpSlider.Value   = 30;   % maximum uphill slope
            app.MaxDownSlider.Value = 15;   % maximum downhill slope

            % 4) Plot the terrain and the initial flight path
            drawTrajectory(app)
        end

        % Value changing function: AltSlider
        function AltSliderValueChanging(app, event)
            drawTrajectory(app)
        end

        % Value changing function: MaxUpSlider
        function MaxUpSliderValueChanging(app, event)
            drawTrajectory(app)
        end

        % Value changing function: MaxDownSlider
        function MaxDownSliderValueChanging(app, event)
            drawTrajectory(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AltSliderLabel = uilabel(app.UIFigure);
            app.AltSliderLabel.Text = 'Alt';
            app.AltSlider = uislider(app.UIFigure);
            app.AltSlider.Limits = [5 100];
            app.AltSlider.ValueChangingFcn = createCallbackFcn(app, @AltSliderValueChanging, true);
            app.AltSlider.Value = 5;
            app.MaxDownSliderLabel = uilabel(app.UIFigure);
            app.MaxDownSliderLabel.Text = 'MaxDown';
            app.MaxDownSlider = uislider(app.UIFigure);
            app.MaxDownSlider.Limits = [0 60];
            app.MaxDownSlider.ValueChangingFcn = createCallbackFcn(app, @MaxDownSliderValueChanging, true);
            app.MaxUpSliderLabel = uilabel(app.UIFigure);
            app.MaxUpSliderLabel.Text = 'MaxUp';
            app.MaxUpSlider = uislider(app.UIFigure);
            app.MaxUpSlider.Limits = [0 60];
            app.MaxUpSlider.ValueChangingFcn = createCallbackFcn(app, @MaxUpSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlyingLow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end