classdef Lab11app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ListBox        matlab.ui.control.ListBox
        ListBoxLabel   matlab.ui.control.Label
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        Button         matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataManager % Description
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            cla(app.UIAxes,"reset");
            plottype=app.DropDown.Value;
            data=app.dataManager.CurrentData;
            
            switch plottype

            case 'Line'
            line(app.UIAxes,data.t,data.signal)

            case 'Bar'
            bar(app.UIAxes,data.t,data.signal)

            case 'Scatter'
            scatter(app.UIAxes,data.t,data.signal)
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dataManager = DataManager();
            fileList = app.dataManager.listDataFiles();
            app.ListBox.Items = fileList;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            selectedFile = app.ListBox.Value;
            app.dataManager=loadData(app.dataManager, selectedFile);
            app.updatePlot(); % A custom method to update the plot
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            ButtonPushed(app, event)
            app.updatePlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Line', 'Bar', 'Scatter'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Line';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab11app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end