classdef Lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotstyleListBox          matlab.ui.control.ListBox
        PlotstyleListBoxLabel     matlab.ui.control.Label
        PlotoptionsDropDown       matlab.ui.control.DropDown
        PlotoptionsDropDownLabel  matlab.ui.control.Label
        WidthSpinner              matlab.ui.control.Spinner
        WidthSpinnerLabel         matlab.ui.control.Label
        AmplitudeSlider           matlab.ui.control.RangeSlider
        AmplitudeSliderLabel      matlab.ui.control.Label
        FrequencySlider           matlab.ui.control.RangeSlider
        FrequencySliderLabel      matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        f = 1/(2*pi); % sin frequency
        att = 0; % attenuation
        thi = 1; %plot thickness
        type = 'line'; % plot type
        plotHandle 
        col = [0 0 1]; % plot color
    end
    
    methods (Access = private)
        
        function plot_data(app)
            x = linspace(0,2.*pi);
            y = 1./(1+app.att.*x) .* sin(app.f.*2.*pi.*x);
            switch app.type
                case 'line'
                    app.plotHandle = plot(app.UIAxes, x, y, 'LineWidth', app.thi);
                    app.plotHandle.Color = app.col;
                
                case 'points'
                    app.plotHandle = plot(app.UIAxes, x, y, 'LineWidth', app.thi);
                    app.plotHandle.MarkerFaceColor = app.col;

                case 'bars'
                    app.plotHandle = plot(app.UIAxes, x, y, 'LineWidth', app.thi);
                    app.plotHandle.LineWidth = app.thi;
                    app.plotHandle.FaceColor = app.col;

                disp(app.plotHandle)

            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: WidthSpinner
        function WidthSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.UIAxes.LineWidth = app.WidthSpinner.Value;
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            app.f = changingValue;
            plot_data(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sinusoid')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure, 'range');
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure, 'range');
            app.WidthSpinnerLabel = uilabel(app.UIFigure);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.UIFigure);
            app.WidthSpinner.ValueChangingFcn = createCallbackFcn(app, @WidthSpinnerValueChanging, true);
            app.PlotoptionsDropDownLabel = uilabel(app.UIFigure);
            app.PlotoptionsDropDownLabel.Text = 'Plot options';
            app.PlotoptionsDropDown = uidropdown(app.UIFigure);
            app.PlotoptionsDropDown.Items = {'Line', 'Points', 'Bars'};
            app.PlotoptionsDropDown.Value = 'Line';
            app.PlotstyleListBoxLabel = uilabel(app.UIFigure);
            app.PlotstyleListBoxLabel.Text = 'Plot style';
            app.PlotstyleListBox = uilistbox(app.UIFigure);
            app.PlotstyleListBox.Items = {'Blue', 'Green', 'Yellow', 'Red', 'Grid off', 'Grid on'};
            app.PlotstyleListBox.Value = 'Blue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end