classdef Laboratory3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AmplitudeSlider       matlab.ui.control.Slider
        AmplitudeSliderLabel  matlab.ui.control.Label
        PhaseSlider           matlab.ui.control.Slider
        PhaseSliderLabel      matlab.ui.control.Label
        FrequencySlider       matlab.ui.control.Slider
        FrequencySliderLabel  matlab.ui.control.Label
        FunctionButtonGroup   matlab.ui.container.ButtonGroup
        x12Button             matlab.ui.control.RadioButton
        SineButton            matlab.ui.control.RadioButton
        Circler1Button        matlab.ui.control.RadioButton
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function  redraw(app)
        if  app.Circler1Button.Value
        t = linspace(0, 2*pi, 400);
        x = cos(t);
        y = sin(t);
        elseif  app.SineButton.Value
        t = linspace(0, 2*pi, 400);
        x = t;
        y = sin(t);
        elseif app.x12Button.Value
        t = linspace(-9, 9, 400);
        x = t;
        y = t.^2 + 2*t + 1;
        end
        xi = x;
            yi = y;

            dx = gradient(x);
            dy = gradient(y);
            dl = cumsum(sqrt(dx.^2 + dy.^2));
            a = app.AmplitudeSlider.Value;
            f = round(app.FrequencySlider.Value);
            p = app.PhaseSlider.Value;
            sine = a * sin(2*pi*f*dl + p);
            xsin = xi + (dy ./ sqrt(dx.^2 + dy.^2)) .* sine;
            ysin = yi + (dx ./ sqrt(dx.^2 + dy.^2)) .* sine;

            plot(app.UIAxes, x, y, 'r');
            hold(app.UIAxes, "on");
            plot(app.UIAxes, xsin, ysin, 'b', 'LineWidth', 1);
            hold(app.UIAxes, "off");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: FunctionButtonGroup
        function FunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.FunctionButtonGroup.SelectedObject;
            redraw(app);
        end

        % Value changing function: AmplitudeSlider
        function AmplitudeSliderValueChanging(app, event)
             app.AmplitudeSlider.Value = event.Value;
            redraw(app);
        end

        % Value changing function: PhaseSlider
        function PhaseSliderValueChanging(app, event)
            app.PhaseSlider.Value= event.Value;
            redraw(app);
            
        end

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
             app.FrequencySlider.Value= event.Value;
            redraw(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Lab 3')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FunctionButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FunctionButtonGroupSelectionChanged, true);
            app.FunctionButtonGroup.Title = 'Function';
            app.Circler1Button = uiradiobutton(app.FunctionButtonGroup);
            app.Circler1Button.Text = 'Circle(r=1)';
            app.Circler1Button.Value = true;
            app.SineButton = uiradiobutton(app.FunctionButtonGroup);
            app.SineButton.Text = ' Sine';
            app.x12Button = uiradiobutton(app.FunctionButtonGroup);
            app.x12Button.Text = ' (x+1)^2';
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.ValueChangingFcn = createCallbackFcn(app, @PhaseSliderValueChanging, true);
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudeSliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laboratory3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end