classdef PolygonalMatryoshka < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        PlotButton    matlab.ui.control.Button
        NSlider       matlab.ui.control.Slider
        NSliderLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    properties (Access = private)
    x = [];
    y = []; % Coordinates
    end
    
    methods (Access = private)
    
        function draw_border(app)
            % Find the index of the highest point
            [~, k] = max(app.y);
            N = numel(app.y);
    
            % If there are 3 or fewer points left, just plot them and clear the arrays.
            if N <= 3
                % Plot whatever remains (if N == 2, it's just a line segment; if N == 1, a single point; if N == 3, the triangle)
                plot(app.UIAxes, app.x, app.y, '-ob', 'LineWidth', 1.5, 'MarkerSize', 6)
                pause(0.5)
    
                % Clear so that the outer loop in PlotButtonPushed stops
                app.x = [];
                app.y = [];
                return
            end
    
            % Start gift‐wrapping (Jarvis march) from the topmost point.
            % Initial “previous” direction vector: we can choose [1 0], e.g. pointing to the right.
            v1 = [1, 0];  
            kmem = k;            % The starting hull index
            hullIdxList = k;     % Keep a list of hull indices, beginning with the topmost
    
            % Plot the starting point in blue
            plot(app.UIAxes, app.x(k), app.y(k), 'ob', 'MarkerSize', 6, 'LineWidth', 1.2)
            pause(0.5)
    
            % Begin wrapping
            j = 0;
            nextIdx = -1;  % placeholder
            while true
                old_k = k;   % store the current hull‐vertex index
                theta_min = inf;
                nextIdx = -1;
    
                % Loop over all points to find the one with the smallest angle from v1
                for i = 1:N
                    if i == k
                        continue
                    end
                    % Compute the unit vector from k to i
                    v2 = [app.x(i) - app.x(k), app.y(i) - app.y(k)];
                    v2 = v2 / norm(v2);
    
                    % Compute cosθ = v1·v2, sinθ = cross‐product z‐component
                    cos_theta = dot(v1, v2);
                    tmp = cross([v1, 0], [v2, 0]);  
                    sin_theta = tmp(3);
    
                    % Compute angle in [0, 360) by using atan2d(sin, cos)
                    theta = atan2d(sin_theta, cos_theta);
                    if theta < 0
                        theta = theta + 360;
                    end
    
                    % If this angle is smaller than any seen so far, pick i
                    if theta < theta_min
                        theta_min = theta;
                        nextIdx = i;
                        best_v2 = v2;  % we’ll need this if we wanted to update v1—but we’ll recompute v1 exactly below
                    end
                end
    
                % If we have come all the way back to kmem, we are done
                if nextIdx == kmem
                    break
                end
    
                % Otherwise, add nextIdx to the hull list
                hullIdxList(end+1) = nextIdx;  
                k = nextIdx;  % move to the next hull index
    
                % Plot that hull vertex
                plot(app.UIAxes, app.x(k), app.y(k), 'ob', 'MarkerSize', 6, 'LineWidth', 1.2)
                pause(0.5)
    
                % Update v1 to be the unit vector from old_k to the newly chosen k
                newVec = [app.x(k) - app.x(old_k), app.y(k) - app.y(old_k)];
                v1 = newVec / norm(newVec);
    
                j = j + 1;
                if j > 3*N  
                    % Just a sanity break to avoid infinite loops
                    warning('Exceeded iteration limit in draw_border().')
                    break
                end
            end
    
            % Close the polygon by appending kmem at the end
            hullIdxList(end+1) = kmem;
    
            % Draw the hull polygon (lines connecting each hull vertex in order)
            plot(app.UIAxes, app.x(hullIdxList), app.y(hullIdxList), '-b', 'LineWidth', 1.5)
            pause(0.5)
    
            % Remove all hull vertices except the last repeated one (kmem) from app.x and app.y
            % hullIdxList(1:end-1) are the unique hull indices
            toRemove = hullIdxList(1:end-1);
            app.x(toRemove) = [];
            app.y(toRemove) = [];
            % Now the next call to draw_border(app) will run on the smaller point set
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            N = floor(app.NSlider.Value);
            app.x = randn(1, N);
            app.y = randn(1, N);
            plot(app.UIAxes, app.x, app.y, "xr")
            grid(app.UIAxes, 'on')
            hold (app.UIAxes, 'on')
            axis(app.UIAxes, 'equal')
            pause(0.5)
            while ~isempty(app.x)
                 draw_border(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [20 100];
            app.NSlider.MajorTicks = [20 30 40 50 60 70 80 90 100];
            app.NSlider.Value = 20;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolygonalMatryoshka

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end