classdef PolynomialHeaven < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        UITable       matlab.ui.control.Table
        PlotButton    matlab.ui.control.Button
        MirrorButton  matlab.ui.control.Button
        DSlider       matlab.ui.control.Slider
        DSliderLabel  matlab.ui.control.Label
        CSlider       matlab.ui.control.Slider
        CSliderLabel  matlab.ui.control.Label
        BSlider       matlab.ui.control.Slider
        BSliderLabel  matlab.ui.control.Label
        ASlider       matlab.ui.control.Slider
        ASliderLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = linspace(-2, 2, 400) % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % 1) Read sliders (each slider ranges from -2 to +2)
            A = app.ASlider.Value;   % coefficient of x^3
            B = app.BSlider.Value;   % coefficient of x^2
            C = app.CSlider.Value;   % coefficient of x
            D = app.DSlider.Value;   % constant term
        
            % 2) Build an x‐vector.  Here we choose [-2, 2] uniformly.
            y = A.*app.x.^3 + B.*app.x.^2 + C.*app.x + D;
        
            % 3) Clear the axes and plot the cubic
            cla(app.UIAxes)                     % clear previous plots
            plot(app.UIAxes, app.x, y, 'b-', 'LineWidth', 2)
            hold(app.UIAxes, 'on')
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
                
            % Tolerance for deciding “zero”
            tol = 1e-8;
        
            % Initialize a cell array of length 3, default to blanks
            %   so that UITable always has 3 cells: {root1, root2, root3}.
            rootsCell = { '', '', '' };
        
            % 1) If A is nonzero, it is a true cubic
            if abs(A) > tol
                R = roots([A, B, C, D]);   % always returns 3 values (possibly complex)
        
                % Put each root into the cell array
                for idx = 1:3
                    % Display the complex root as-is. If you prefer to
                    % skip purely‐imaginaries or format differently, you could tweak here.
                    rootsCell{idx} = R(idx);
                end
        
                % Mark any roots that are “real” (imag part < tol)
                isRealRoot = abs(imag(R)) < tol;
                realRoots  = real(R(isRealRoot));
                scatter(app.UIAxes, realRoots, zeros(size(realRoots)), 60, 'ro', 'filled');
        
            else
                % 2) A is (nearly) zero → we no longer have a cubic. Check B:
                if abs(B) > tol
                    % It's a quadratic: B x^2 + C x + D = 0
                    R2 = roots([B, C, D]);   % returns up to 2 values
        
                    % Fill in the first two table columns
                    rootsCell{1} = R2(1);
                    if numel(R2) >= 2
                        rootsCell{2} = R2(2);
                    end
        
                    % Mark any real quadratic roots at y=0
                    isRealRoot = abs(imag(R2)) < tol;
                    realRoots  = real(R2(isRealRoot));
                    scatter(app.UIAxes, realRoots, zeros(size(realRoots)), 60, 'ro', 'filled');
        
                elseif abs(C) > tol
                    % It's linear: C x + D = 0  → x = -D/C
                    r1 = -D / C;
                    rootsCell{1} = r1;
        
                    % Mark that single real root
                    scatter(app.UIAxes, r1, 0, 60, 'ro', 'filled');
        
                else
                    % A≈0, B≈0, C≈0 → it’s a constant P(x)=D. Either
                    %   • D=0  → every x is a root (we could choose not to mark anything), or
                    %   • D≠0  → no solution at all.
                    if abs(D) < tol
                        % “All x” is a root; but we can’t list infinitely many. 
                        % We’ll just put “All x” in Root 1 and leave the rest blank:
                        rootsCell{1} = 'All real x';
                    else
                        % No root exists: we’ll leave all three slots blank
                        % or maybe put “∅” (empty‐set) in the first column:
                        rootsCell{1} = '∅';  
                    end
                end
            end
        
            % 3) Populate the UITable with our 1×3 cell array:
            app.UITable.Data = rootsCell;
        
            hold(app.UIAxes, 'off')

        end

        % Button pushed function: MirrorButton
        function MirrorButtonPushed(app, event)
            % 1) Read the four coefficients A, B, C, D from the sliders
            A = app.ASlider.Value;
            B = app.BSlider.Value;
            C = app.CSlider.Value;
            D = app.DSlider.Value;
        
            % 2) Grab the current axis limits BEFORE adding the mirror curve
            %    (These are 1×2 vectors: [xmin xmax] and [ymin ymax].)
            origXLim = app.UIAxes.XLim;
            origYLim = app.UIAxes.YLim;
        
            % 3) Build a small epsilon so we never evaluate at x=0
            epsVal = 0.01;
        
            %    Split the domain into two pieces: [-2, -epsVal] and [epsVal, 2].
            x_left  = linspace(-2,   -epsVal, 200).';
            x_right = linspace( epsVal, 2,      200).';
        
            % 4) Compute P(1/x) on each piece
            y_left  = A.*(1./x_left).^3  +  B.*(1./x_left).^2  +  C.*(1./x_left)  +  D;
            y_right = A.*(1./x_right).^3 +  B.*(1./x_right).^2 +  C.*(1./x_right) +  D;
        
            % 5) Overlay the mirror curve in red dashed, but keep the old axes limits
            hold(app.UIAxes, 'on');
                plot(app.UIAxes, x_left,  y_left,  'r--', 'LineWidth', 1.5);
                plot(app.UIAxes, x_right, y_right, 'r--', 'LineWidth', 1.5);
            hold(app.UIAxes, 'off');
        
            % 6) Restore the original axis limits so they don’t auto‐rescale
            app.UIAxes.XLim = origXLim;
            app.UIAxes.YLim = origYLim;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graphical Representation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ASliderLabel = uilabel(app.UIFigure);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.UIFigure);
            app.ASlider.Limits = [-2 2];
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [-2 2];
            app.CSliderLabel = uilabel(app.UIFigure);
            app.CSliderLabel.Text = 'C';
            app.CSlider = uislider(app.UIFigure);
            app.CSlider.Limits = [-2 2];
            app.DSliderLabel = uilabel(app.UIFigure);
            app.DSliderLabel.Text = 'D';
            app.DSlider = uislider(app.UIFigure);
            app.DSlider.Limits = [-2 2];
            app.MirrorButton = uibutton(app.UIFigure, 'push');
            app.MirrorButton.ButtonPushedFcn = createCallbackFcn(app, @MirrorButtonPushed, true);
            app.MirrorButton.Text = 'Mirror';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Root 1'; 'Root 2'; 'Root 3'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PolynomialHeaven

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end