classdef SidonSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Set           matlab.ui.control.TextArea
        TextArea      matlab.ui.control.TextArea
        PlotButton    matlab.ui.control.Button
        NSlider       matlab.ui.control.Slider
        NSliderLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            N = round(app.NSlider.Value);
            if N < 1
                app.TextArea.Value = "N must be greater than 0";
                app.TextArea.BackgroundColor = [1 0 0];
                return
            end

            S = zeros(1, N);   
            S(1) = 1;         
            sums = S(1) + S(1);
            count = 1;
            candidate = 2;
    
            while count < N
                newSums = [ S(1:count) + candidate, candidate + candidate ];
    
                if isempty(intersect(newSums, sums))
                    count = count + 1;
                    S(count) = candidate;
                    sums = [sums, newSums];
                end
                candidate = candidate + 1;
            end
    
            cla(app.UIAxes)
    
            maxSum = max(sums);
            bins   = 0 : maxSum;  
    
            freq = histcounts(sums, [bins, maxSum+1]);

            if all(freq <= 1)
                app.TextArea.Value = "Sidonicity found";
                app.TextArea.BackgroundColor = [0 1 0];  
            else
                app.TextArea.Value = "Not a Sidon set";
                app.TextArea.BackgroundColor = [1 0 0];
            end

            app.Set.Value = num2str(S);

    
            bar(app.UIAxes, bins, freq, 'FaceColor', [0.7 0.7 0.7]);
            hold(app.UIAxes, 'on')
    
            freqMax = max(freq);
            if freqMax == 0
                freqMax = 1;
            end
            markerY = -0.05 * freqMax;  
    
            plot(app.UIAxes, S, markerY * ones(size(S)), 'r*', ...
                 'MarkerSize', 8, 'LineWidth', 1.5);
    
            hold(app.UIAxes, 'off')
    
            app.UIAxes.YLim = [-0.1*freqMax, freqMax];
    
            xlabel(app.UIAxes, 'Integer Value');
            ylabel(app.UIAxes, 'Frequency of Pairwise Sums');
            title(app.UIAxes, sprintf('Sidon Set of Size N = %d', N));
    
            legend(app.UIAxes, 'Sum Histogram','Set Elements', ...
                   'Location','northwest')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NSliderLabel = uilabel(app.UIFigure);
            app.NSliderLabel.Text = 'N';
            app.NSlider = uislider(app.UIFigure);
            app.NSlider.Limits = [0 10];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Placeholder = 'Sidonicity';
            app.Set = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SidonSet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end