classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PaEditField          matlab.ui.control.NumericEditField
        PaEditFieldLabel     matlab.ui.control.Label
        PressureLabel        matlab.ui.control.Label
        HeightaboveMSLLabel  matlab.ui.control.Label
        ftEditField          matlab.ui.control.NumericEditField
        ftEditFieldLabel     matlab.ui.control.Label
        mEditField           matlab.ui.control.NumericEditField
        mEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        ft2m = 0.3048; % Description
    end
    
    methods (Access = private)
        
        function compute_pressure(app)
            [~, ~, app.PaEditField.Value, ~] = atmosisa(app.mEditField.Value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ftEditField
        function ftEditFieldValueChanged(app, event)
            value = app.ftEditField.Value;
            app.mEditField.Value = app.ftEditField.Value .* app.ft2m;

            compute_pressure(app);
        end

        % Value changed function: mEditField
        function mEditFieldValueChanged(app, event)
            value = app.mEditField.Value;
            app.ftEditField.Value = app.mEditField.Value / app.ft2m;
            compute_pressure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = '[m]';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField.ValueChangedFcn = createCallbackFcn(app, @mEditFieldValueChanged, true);
            app.ftEditFieldLabel = uilabel(app.UIFigure);
            app.ftEditFieldLabel.Text = '[ft]';
            app.ftEditField = uieditfield(app.UIFigure, 'numeric');
            app.ftEditField.ValueChangedFcn = createCallbackFcn(app, @ftEditFieldValueChanged, true);
            app.HeightaboveMSLLabel = uilabel(app.UIFigure);
            app.HeightaboveMSLLabel.Text = 'Height above MSL';
            app.PressureLabel = uilabel(app.UIFigure);
            app.PressureLabel.Text = 'Pressure';
            app.PaEditFieldLabel = uilabel(app.UIFigure);
            app.PaEditFieldLabel.Text = 'Pa';
            app.PaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end