classdef labwork2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        PlotDataButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            t = linspace(0, 2*pi);
            amp = sin(t);
            plot(app.UIAxes, t, amp);
            title(app.UIAxes, "amp = sin(t)");
            grid(app.UIAxes, "on");
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            clickedPosition = app.UIAxes.CurrentPoint;
            % disp(clickedPosition)
            xp = clickedPosition(1, 1);
            yp = clickedPosition(1, 2);
            disp(['Clicked at: ', '(', num2str(xp), num2str(yp), ')']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'sin(t)')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'f(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PlotDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labwork2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end